classdef testappdesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        Plot3DButton        matlab.ui.control.Button
        PlotRatioButton     matlab.ui.control.Button
        PlotContourButton   matlab.ui.control.Button
        PlotMhButton        matlab.ui.control.Button
        ViewButtonGroup     matlab.ui.container.ButtonGroup
        RadioButton3D       matlab.ui.control.RadioButton
        RadioButtonXY       matlab.ui.control.RadioButton
        RadioButtonYZ       matlab.ui.control.RadioButton
        RadioButtonXZ       matlab.ui.control.RadioButton
        Label_11            matlab.ui.control.Label
        Z0EditField         matlab.ui.control.EditField
        Z0EditFieldLabel    matlab.ui.control.Label
        Y0EditField         matlab.ui.control.EditField
        Y0EditFieldLabel    matlab.ui.control.Label
        X0EditField         matlab.ui.control.EditField
        X0EditFieldLabel    matlab.ui.control.Label
        N_KnotEditField     matlab.ui.control.EditField
        Label_10            matlab.ui.control.Label
        ZminEditField       matlab.ui.control.EditField
        ZminEditFieldLabel  matlab.ui.control.Label
        ZmaxEditField       matlab.ui.control.NumericEditField
        ZmaxEditFieldLabel  matlab.ui.control.Label
        YmaxEditField       matlab.ui.control.EditField
        YmaxEditFieldLabel  matlab.ui.control.Label
        YminEditField       matlab.ui.control.EditField
        YminEditFieldLabel  matlab.ui.control.Label
        XminEditField       matlab.ui.control.EditField
        XminEditFieldLabel  matlab.ui.control.Label
        XmaxEditField       matlab.ui.control.NumericEditField
        XmaxEditFieldLabel  matlab.ui.control.Label
        CurrentEditField    matlab.ui.control.EditField
        Label_9             matlab.ui.control.Label
        NCoilEditField      matlab.ui.control.EditField
        Label_8             matlab.ui.control.Label
        RadiusEditField     matlab.ui.control.EditField
        EditField_2Label    matlab.ui.control.Label
        LengthEditField     matlab.ui.control.NumericEditField
        Label_7             matlab.ui.control.Label
        Label_2             matlab.ui.control.Label
        Label               matlab.ui.control.Label
        ComputeButton       matlab.ui.control.Button
        RightPanel          matlab.ui.container.Panel
        TextArea            matlab.ui.control.TextArea
        TextAreaLabel       matlab.ui.control.Label
        ReadDataButton      matlab.ui.control.Button
        Mode2Button         matlab.ui.control.Button
        Mode1Button         matlab.ui.control.Button
        NStepEditField      matlab.ui.control.EditField
        Label_15            matlab.ui.control.Label
        dREditField         matlab.ui.control.EditField
        Label_14            matlab.ui.control.Label
        PhiEditField        matlab.ui.control.EditField
        Label_13            matlab.ui.control.Label
        ModREditField       matlab.ui.control.EditField
        EditField_7Label    matlab.ui.control.Label
        Label_12            matlab.ui.control.Label
        Image               matlab.ui.control.Image
        X00Label            matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Radius;
        Solenoid_Length  
        N_Coil 
        I
        Xmin;
        Xmax
        Ymin
        Ymax
        Zmin
        Zmax
        N_Knot
        X0
        Y0
        Z0
        Mod_R
        Phi
        dR
        N_Step
        % Данные для построения
        A2D     % для контурных карт
        Xmesh     % для 3D-срезов
        Ymesh
        Zmesh
        AxData    % векторы поля
        AyData
        AzData
        R         % радиусы для графика Mh
        Mh       % значения M_h
        Mode     % режим (1 или 2)
    end
    
    methods (Access = public)
        
        
                % Считывание данных из полей ввода
        function readData(app)
            app.Radius         = str2double(app.RadiusEditField.Value);
            app.Solenoid_Length= app.LengthEditField.Value;
            app.N_Coil         = str2double(app.NCoilEditField.Value);
            app.I              = str2double(app.CurrentEditField.Value);
            app.Xmin           = str2double(app.XminEditField.Value);
            app.Xmax           = app.XmaxEditField.Value;
            app.Ymin           = str2double(app.YminEditField.Value);
            app.Ymax           = str2double(app.YmaxEditField.Value);
            app.Zmin           = str2double(app.ZminEditField.Value);
            app.Zmax           = app.ZmaxEditField.Value;
            app.N_Knot         = str2double(app.N_KnotEditField.Value);
            app.X0             = str2double(app.X0EditField.Value);
            app.Y0             = str2double(app.Y0EditField.Value);
            app.Z0             = str2double(app.Z0EditField.Value);
            app.Mod_R          = str2double(app.ModREditField.Value);
            app.Phi            = str2double(app.PhiEditField.Value);
            app.dR             = str2double(app.dREditField.Value);
            app.N_Step         = str2double(app.NStepEditField.Value);
            
        end
        
        function z = Bx(app, x0, y0, z0, a, h, N, I)
            phi = linspace(0, 2*pi*N, 200);
            f = I * ((z0 - phi*h/(2*pi))*a .* cos(phi) - h/(2*pi)*(y0 - a*sin(phi))) ./ ...
                ((x0^2 + y0^2 + z0^2 + a^2 + (h/(2*pi)*phi).^2 - 2*(x0*a*cos(phi) + a*y0*sin(phi) + (h/(2*pi))*z0*phi)).^(3/2));
            z = trapz(phi, f);
        end
        function z = By(app, x0, y0, z0, a, h, N, I)
            phi = linspace(0, 2*pi*N, 200);
            f = I * ((x0 - a*cos(phi))*h/(2*pi) + a*sin(phi).*(z0 - phi*h/(2*pi))) ./ ...
                ((x0^2 + y0^2 + z0^2 + a^2 + (h/(2*pi)*phi).^2 - 2*(x0*a*cos(phi) + a*y0*sin(phi) + (h/(2*pi))*z0*phi)).^(3/2));
            z = trapz(phi, f);
        end
        function z = Bz(app, x0, y0, z0, a, h, N, I)
            phi = linspace(0, 2*pi*N, 200);
            f = I * ((y0 - a*sin(phi))*a.*sin(phi) + a*cos(phi).*(x0 - a*cos(phi))) ./ ...
                ((x0^2 + y0^2 + z0^2 + a^2 + (h/(2*pi)*phi).^2 - 2*(x0*a*cos(phi) + a*y0*sin(phi) + (h/(2*pi))*z0*phi)).^(3/2));
            z = trapz(phi, f);
        end
        function z = Ax(app, x0, y0, z0, a, h, N, I)
            phi = linspace(0, 2*pi*N, 200);
            f = -I*(a*sin(phi)) ./ sqrt((x0 - a*cos(phi)).^2 + (y0 - a*sin(phi)).^2 + (z0 - h/(2*pi)*phi).^2);
            z = trapz(phi, f);
        end
        function z = Ay(app, x0, y0, z0, a, h, N, I)
            phi = linspace(0, 2*pi*N, 200);
            f = I*(a*cos(phi)) ./ sqrt((x0 - a*cos(phi)).^2 + (y0 - a*sin(phi)).^2 + (z0 - h/(2*pi)*phi).^2);
            z = trapz(phi, f);
        end
        function z = Az(app, x0, y0, z0, a, h, N, I)
            phi = linspace(0, 2*pi*N, 200);
            f = (I*h/(2*pi)) ./ sqrt((x0 - a*cos(phi)).^2 + (y0 - a*sin(phi)).^2 + (z0 - h/(2*pi)*phi).^2);
            z = trapz(phi, f);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            app.readData
            h = app.Solenoid_Length / app.N_Coil;
            %app.TextArea.Value = sprintf('banan %.1f', h);
            sel = app.ViewButtonGroup.SelectedObject;
            % Вычисляем двумерные карты A2D для выбранной плоскости
            switch sel
                case app.RadioButtonYZ
                    
                    
                    app.TextArea.Value = sprintf('Карта Y-Z при X0=%.2f готова', app.X0);
                case app.RadioButtonXZ
                    x = linspace(app.Xmin, app.Xmax, app.N_Knot);
                    z = linspace(app.Zmin, app.Zmax, app.N_Knot);
                    [Xg, Zg] = meshgrid(x, z);
                    A = zeros(size(Xg));
                    for i = 1:app.N_Knot
                        for j = 1:app.N_Knot
                            ax = app.Ax(Xg(i,j), app.Y0, Zg(i,j), app.Radius, h, app.N_Coil, app.I);
                            ay = app.Ay(Xg(i,j), app.Y0, Zg(i,j), app.Radius, h, app.N_Coil, app.I);
                            az = app.Az(Xg(i,j), app.Y0, Zg(i,j), app.Radius, h, app.N_Coil, app.I);
                            A(i,j) = sqrt(ax^2 + ay^2 + az^2);
                        end
                    end
                    app.A2D = A; app.Xmesh = x; app.Ymesh = z;
                    %app.TextArea.Value = sprintf('Карта X-Z при Y0=%.2f готова', app.Y0);
                case app.RadioButtonXY
                    x = linspace(app.Xmin, app.Xmax, app.N_Knot);
                    y = linspace(app.Ymin, app.Ymax, app.N_Knot);
                    [Xg, Yg] = meshgrid(x, y);
                    A = zeros(size(Xg));
                    for i = 1:app.N_Knot
                        for j = 1:app.N_Knot
                            ax = app.Ax(Xg(i,j), Yg(i,j), app.Z0, app.Radius, h, app.N_Coil, app.I);
                            ay = app.Ay(Xg(i,j), Yg(i,j), app.Z0, app.Radius, h, app.N_Coil, app.I);
                            az = app.Az(Xg(i,j), Yg(i,j), app.Z0, app.Radius, h, app.N_Coil, app.I);
                            A(i,j) = sqrt(ax^2 + ay^2 + az^2);
                        end
                    end
                    app.A2D = A; app.Xmesh = x; app.Ymesh = y;
                    app.TextArea.Value = sprintf('Карта X-Y при Z0=%.2f готова', app.Z0);
                case app.RadioButton3D
                    % Вычисляем поле в объеме
                    x = linspace(app.Xmin, app.Xmax, app.N_Knot);
                    y = linspace(app.Ymin, app.Ymax, app.N_Knot);
                    z = linspace(app.Zmin, app.Zmax, app.N_Knot);
                    [Xg, Yg, Zg] = meshgrid(x, y, z);
                    Axm = zeros(size(Xg)); Aym = Axm; Azm = Axm;
                    for ix = 1:app.N_Knot
                        for iy = 1:app.N_Knot
                            for iz = 1:app.N_Knot
                                Axm(ix,iy,iz) = app.Ax(Xg(ix,iy,iz), Yg(ix,iy,iz), Zg(ix,iy,iz), app.Radius, h, app.N_Coil, app.I);
                                Aym(ix,iy,iz) = app.Ay(Xg(ix,iy,iz), Yg(ix,iy,iz), Zg(ix,iy,iz), app.Radius, h, app.N_Coil, app.I);
                                Azm(ix,iy,iz) = app.Az(Xg(ix,iy,iz), Yg(ix,iy,iz), Zg(ix,iy,iz), app.Radius, h, app.N_Coil, app.I);
                            end
                        end
                    end
                    app.Xmesh = x; app.Ymesh = y; app.Zmesh = z;
                    app.AxData = Axm; app.AyData = Aym; app.AzData = Azm;
                    app.TextArea.Value = '3D поле рассчитано';
            end
            app.PlotContourButton.Enable = 'on';
            app.Plot3DButton.Enable      = 'on';
            app.Mode1Button.Enable       = 'on';
            app.Mode2Button.Enable       = 'on';
        
           
        end

        % Button pushed function: PlotContourButton
        function PlotContourButtonPushed(app, event)
            cla(app.UIAxes);
            contour(app.UIAxes, app.Xmesh, app.Ymesh, app.A2D);
            hold(app.UIAxes, 'on');
            % Рисуем контур соленоида
            line(app.UIAxes, [-app.Radius/2, app.Radius/2, app.Radius/2, -app.Radius/2, -app.Radius/2],...
                                   [0, 0, app.Solenoid_Length, app.Solenoid_Length, 0], 'Color','k');
            hold(app.UIAxes, 'off');
            app.TextArea.Value = 'Контур построен';
        end

        % Button pushed function: Plot3DButton
        function Plot3DButtonPushed(app, event)
            cla(app.UIAxes);
            slice(app.UIAxes, app.Xmesh, app.Ymesh, app.Zmesh, app.AxData, app.Xmax, app.Ymax, app.Zmin);
            hold(app.UIAxes, 'on');
            streamslice(app.UIAxes, app.Xmesh, app.Ymesh, app.Zmesh, app.AxData, app.AyData, app.AzData);
            view(app.UIAxes, 3); grid(app.UIAxes, 'on'); daspect(app.UIAxes, [2,2,1]);
            hold(app.UIAxes, 'off');
            app.TextArea.Value = '3D визуализация готова';
        end

        % Button pushed function: Mode1Button
        function Mode1ButtonPushed(app, event)
            app.Mode = 1;
            app.readData();
            h = app.Solenoid_Length / app.N_Coil;
            phi = app.Phi;
            R0 = app.Mod_R;
            dx = app.dR*cos(phi); dy = app.dR*sin(phi);
            for i = 1:app.N_Step
                x1(i) = R0*cos(phi) + (i-1)*dx;
                y1(i) = R0*sin(phi) + (i-1)*dy;
                Hx(i) = app.Bx(x1(i), y1(i), app.Z0, app.Radius, h, app.N_Coil, app.I);
                Hy(i) = app.By(x1(i), y1(i), app.Z0, app.Radius, h, app.N_Coil, app.I);
            end
            app.R  = hypot(x1, y1);
            app.Mh = hypot(Hx, Hy)/hypot(Hx(1), Hy(1));
            app.PlotMhButton.Enable    = 'on';
            app.PlotRatioButton.Enable = 'on';
            app.TextArea.Value   = 'Mode 1 ready';
        end

        % Button pushed function: Mode2Button
        function Mode2ButtonPushed(app, event)
            app.Mode = 2;
            app.readData();
            h = app.Solenoid_Length / app.N_Coil;
            phi = app.Phi; dx = app.dR*cos(phi); dy = app.dR*sin(phi);
            for i = 1:app.N_Step
                x1(i) = app.Mod_R*cos(phi) + (i-1)*dx;
                y1(i) = app.Mod_R*sin(phi) + (i-1)*dy;
                Hx(i) = app.Bx(x1(i), y1(i), app.Z0, app.Radius, h, app.N_Coil, app.I);
                Hy(i) = app.By(x1(i), y1(i), app.Z0, app.Radius, h, app.N_Coil, app.I);
                Hz(i) = app.Bz(x1(i), y1(i), app.Z0, app.Radius, h, app.N_Coil, app.I);
            end
            app.R  = hypot(x1, y1);
            Mh = hypot(Hx, Hy);
            app.Mh = Mh ./ abs(Hz);
            app.PlotMhButton.Enable    = 'on';
            app.PlotRatioButton.Enable = 'on';
            app.TextArea.Value   = 'Mode 2 ready';
        end

        % Plot M_h
        function PlotMhButtonPushed(app, event)
            plot(app.UIAxes, app.R, app.Mh);
            xlabel(app.UIAxes, 'R'); ylabel(app.UIAxes, 'M_h');
            title(app.UIAxes, 'M_h vs R');
            app.TextArea.Value = sprintf('График M_h для Mode %d построен', app.Mode);
        end

        % Button pushed function: PlotRatioButton
        function PlotRatioButtonPushed(app, event)
            if app.Mode == 2
                plot(app.UIAxes, app.R, app.Mh);
                xlabel(app.UIAxes, 'R'); ylabel(app.UIAxes, 'M_h / H_z');
                title(app.UIAxes, 'Ratio M_h/H_z vs R');
                app.TextArea.Value = 'График M_h/H_z построен';
            else
                app.TextArea.Value = 'Режим 2 не выбран';
            end
        end

        % Callback function: not associated with a component
        function ReadDataButton_2Pushed(app, event)


        end

        % Button pushed function: ReadDataButton
        function ReadDataButtonPushed(app, event)
            
            app.readData();
            app.TextArea.Value = 'Данные считаны';
            app.ComputeButton.Enable = 'on';
        
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {533, 533};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {340, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ComputeButton = uibutton(app.LeftPanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'построить график';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = 'Параметры соленоида';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = 'Параметры сетки';
            app.Label_7 = uilabel(app.LeftPanel);
            app.Label_7.Text = 'Длина(см):';
            app.LengthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LengthEditField.Limits = [0 Inf];
            app.LengthEditField.ValueDisplayFormat = '%.1f';
            app.LengthEditField.Value = 6;
            app.EditField_2Label = uilabel(app.LeftPanel);
            app.EditField_2Label.Text = 'Радиус(см):';
            app.RadiusEditField = uieditfield(app.LeftPanel, 'text');
            app.RadiusEditField.Value = '0.5';
            app.Label_8 = uilabel(app.LeftPanel);
            app.Label_8.Text = 'Число витков:';
            app.NCoilEditField = uieditfield(app.LeftPanel, 'text');
            app.NCoilEditField.Value = '200';
            app.Label_9 = uilabel(app.LeftPanel);
            app.Label_9.Text = 'Сила тока(А):';
            app.CurrentEditField = uieditfield(app.LeftPanel, 'text');
            app.CurrentEditField.Value = '1.0';
            app.XmaxEditFieldLabel = uilabel(app.LeftPanel);
            app.XmaxEditFieldLabel.Text = 'Xmax(см):';
            app.XmaxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.XmaxEditField.ValueDisplayFormat = '%.1f';
            app.XmaxEditField.Value = 5;
            app.XminEditFieldLabel = uilabel(app.LeftPanel);
            app.XminEditFieldLabel.Text = 'Xmin(см):';
            app.XminEditField = uieditfield(app.LeftPanel, 'text');
            app.XminEditField.Value = '-5.0';
            app.YminEditFieldLabel = uilabel(app.LeftPanel);
            app.YminEditFieldLabel.Text = 'Ymin(см):';
            app.YminEditField = uieditfield(app.LeftPanel, 'text');
            app.YminEditField.Value = '-5.0';
            app.YmaxEditFieldLabel = uilabel(app.LeftPanel);
            app.YmaxEditFieldLabel.Text = 'Ymax(см):';
            app.YmaxEditField = uieditfield(app.LeftPanel, 'text');
            app.YmaxEditField.Value = '5.0';
            app.ZmaxEditFieldLabel = uilabel(app.LeftPanel);
            app.ZmaxEditFieldLabel.Text = 'Zmax(см):';
            app.ZmaxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ZmaxEditField.ValueDisplayFormat = '%.1f';
            app.ZmaxEditField.Value = 8;
            app.ZminEditFieldLabel = uilabel(app.LeftPanel);
            app.ZminEditFieldLabel.Text = 'Zmin(см):';
            app.ZminEditField = uieditfield(app.LeftPanel, 'text');
            app.ZminEditField.Value = '-5.0';
            app.Label_10 = uilabel(app.LeftPanel);
            app.Label_10.Text = 'Число узлов:';
            app.N_KnotEditField = uieditfield(app.LeftPanel, 'text');
            app.N_KnotEditField.Value = '17';
            app.X0EditFieldLabel = uilabel(app.LeftPanel);
            app.X0EditFieldLabel.Text = 'X0(см):';
            app.X0EditField = uieditfield(app.LeftPanel, 'text');
            app.X0EditField.Value = '0.0';
            app.Y0EditFieldLabel = uilabel(app.LeftPanel);
            app.Y0EditFieldLabel.Text = 'Y0(см):';
            app.Y0EditField = uieditfield(app.LeftPanel, 'text');
            app.Y0EditField.Value = '0.0';
            app.Z0EditFieldLabel = uilabel(app.LeftPanel);
            app.Z0EditFieldLabel.Text = 'Z0(см):';
            app.Z0EditField = uieditfield(app.LeftPanel, 'text');
            app.Z0EditField.Value = '0.0';
            app.Label_11 = uilabel(app.LeftPanel);
            app.Label_11.Text = 'Расположение сетки';
            app.ViewButtonGroup = uibuttongroup(app.LeftPanel);
            app.RadioButtonXZ = uiradiobutton(app.ViewButtonGroup);
            app.RadioButtonXZ.Tag = 'RadioButtonXZ';
            app.RadioButtonXZ.Text = 'В плоскости,перпендикулярной оси Ox';
            app.RadioButtonXZ.Value = true;
            app.RadioButtonYZ = uiradiobutton(app.ViewButtonGroup);
            app.RadioButtonYZ.Tag = 'RadioButtonYZ';
            app.RadioButtonYZ.Text = 'В плоскости,перпендикулярной оси Oy';
            app.RadioButtonXY = uiradiobutton(app.ViewButtonGroup);
            app.RadioButtonXY.Tag = 'RadioButtonXY';
            app.RadioButtonXY.Text = 'В плоскости,перпендикулярной оси Oz';
            app.RadioButton3D = uiradiobutton(app.ViewButtonGroup);
            app.RadioButton3D.Tag = 'RadioButton3D';
            app.RadioButton3D.Text = 'В пространстве';
            app.PlotMhButton = uibutton(app.LeftPanel, 'push');
            app.PlotMhButton.Text = 'Показать сетку ';
            app.PlotContourButton = uibutton(app.LeftPanel, 'push');
            app.PlotContourButton.ButtonPushedFcn = createCallbackFcn(app, @PlotContourButtonPushed, true);
            app.PlotContourButton.Text = 'Вычисление вектор потенциала';
            app.PlotRatioButton = uibutton(app.LeftPanel, 'push');
            app.PlotRatioButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRatioButtonPushed, true);
            app.PlotRatioButton.Text = 'Выход';
            app.Plot3DButton = uibutton(app.LeftPanel, 'push');
            app.Plot3DButton.ButtonPushedFcn = createCallbackFcn(app, @Plot3DButtonPushed, true);
            app.Plot3DButton.Text = 'построить график 3d';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-5 5];
            app.UIAxes.YLim = [-5 5];
            app.UIAxes.ZLim = [-5 5];
            app.X00Label = uilabel(app.RightPanel);
            app.X00Label.Text = 'Координатная сетка в плоскости X0 = 0';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'pinki.png');
            app.Label_12 = uilabel(app.RightPanel);
            app.Label_12.Text = 'Параметры радиус-вектора';
            app.EditField_7Label = uilabel(app.RightPanel);
            app.EditField_7Label.Text = 'Начальная длина радиус вектора(см):';
            app.ModREditField = uieditfield(app.RightPanel, 'text');
            app.ModREditField.Value = '1.0';
            app.Label_13 = uilabel(app.RightPanel);
            app.Label_13.Text = 'Направление радиус ветора(град):';
            app.PhiEditField = uieditfield(app.RightPanel, 'text');
            app.PhiEditField.Value = '0.0';
            app.Label_14 = uilabel(app.RightPanel);
            app.Label_14.Text = 'Праращение длины радиус вектора(см):';
            app.dREditField = uieditfield(app.RightPanel, 'text');
            app.dREditField.Value = '0.1';
            app.Label_15 = uilabel(app.RightPanel);
            app.Label_15.Text = 'Число приращений радиус вектора:';
            app.NStepEditField = uieditfield(app.RightPanel, 'text');
            app.NStepEditField.Value = '10';
            app.Mode1Button = uibutton(app.RightPanel, 'push');
            app.Mode1Button.ButtonPushedFcn = createCallbackFcn(app, @Mode1ButtonPushed, true);
            app.Mode1Button.Text = 'Вычисление напряженности в плоскости,перпендикулярной оси Oz';
            app.Mode2Button = uibutton(app.RightPanel, 'push');
            app.Mode2Button.ButtonPushedFcn = createCallbackFcn(app, @Mode2ButtonPushed, true);
            app.Mode2Button.Text = 'Вычисление отношение напряженностей';
            app.ReadDataButton = uibutton(app.RightPanel, 'push');
            app.ReadDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadDataButtonPushed, true);
            app.ReadDataButton.Text = 'прочитать фигни';
            app.TextAreaLabel = uilabel(app.RightPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testappdesigner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end